classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        GridLayout     matlab.ui.container.GridLayout
        LeftPanel      matlab.ui.container.Panel
        STOPButton     matlab.ui.control.Button
        TextArea       matlab.ui.control.TextArea
        TextAreaLabel  matlab.ui.control.Label
        DISPLAYButton  matlab.ui.control.Button
        STARTButton    matlab.ui.control.Button
        RightPanel     matlab.ui.container.Panel
        UIAxes         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        sig1;
        sig2;
        sig3;
        sig4;
        Fs; 
        stopProcessing;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
             app.STARTButton.Enable = 'off';
             app.stopProcessing = false;
        
            if (~app.stopProcessing)
            % Start processing
            app.TextArea.Value = "Initializing...";
            system(['C:\Users\user\Desktop\UCT 2023\EEE3097S DESIGN\3097-Group-8-2023\Pi Connection\master_low.bat']);
            system(['C:\Users\user\Desktop\UCT 2023\EEE3097S DESIGN\3097-Group-8-2023\Pi Connection\pi_rec.bat']);
            end
            
            if (~app.stopProcessing)
            % Show the first prompt after 6 seconds
            pause(6)
            app.TextArea.Value = 'Play Calibration sound now and move to source after and wait!';
            end

            if (~app.stopProcessing)
            % Show the second prompt after 16 seconds
            pause(16);
            app.TextArea.Value = 'Play sound at source postion! and wait.';
            end

            if (~app.stopProcessing)
            % Wait for end of recording
            pause(18)
            app.TextArea.Value = "Finished recording, transferring files...";
            end

            if (~app.stopProcessing)
            system(['C:\Users\user\Desktop\UCT 2023\EEE3097S DESIGN\3097-Group-8-2023\Pi Connection\scp_files.bat']); 
            pause(5)
            end

            if (~app.stopProcessing)
            app.TextArea.Value = "Transfer Complete.Press Display to show results!";
            [file1,app.Fs] = audioread("pi1.wav");
            [file2,app.Fs] = audioread("pi2.wav");
            end

            if (~app.stopProcessing)
            app.sig1 = file2(:,1);  %Left
            app.sig2 = file2(:,2);  %Right
            app.sig3 = file1(:,1);  %Left
            app.sig4 = file1(:,2);  %Right
           

            %assignin('base', 'sig1', sig1);
            %assignin('base', 'sig2', sig2);
            %assignin('base', 'sig3', sig3);
            %assignin('base', 'sig4', sig4);
            %assignin('base', 'Fs', Fs);
            %localize(sig1,sig2,sig3,sig4,Fs)
            end
    
            % Re-enable the start button
            app.STARTButton.Enable = 'on';

            if (app.stopProcessing)
            app.TextArea.Value = "Recording cancelled.";
            end
        end

        % Button pushed function: DISPLAYButton
        function DISPLAYButtonPushed(app, event)
            %sig1 = evalin('base', 'sig1');
            %sig2 = evalin('base', 'sig2');
            %sig3 = evalin('base', 'sig3');
            %sig4 = evalin('base', 'sig4');
            %Fs = evalin('base', 'Fs');
            if (~app.stopProcessing)
            [file1,app.Fs] = audioread("C:\Users\user\Desktop\UCT 2023\EEE3097S DESIGN\3097-Group-8-2023\Gui\pi1.wav");
            [file2,app.Fs] = audioread("C:\Users\user\Desktop\UCT 2023\EEE3097S DESIGN\3097-Group-8-2023\Gui\pi2.wav");
            end

            if (~app.stopProcessing)
            app.sig1 = file2(:,1);  %Left
            app.sig2 = file2(:,2);  %Right
            app.sig3 = file1(:,1);  %Left
            app.sig4 = file1(:,2);  %Right
            end

            localize(app.UIAxes,app.sig1,app.sig2,app.sig3,app.sig4,app.Fs);
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            % Kill the recording
            app.stopProcessing = true;
            app.TextArea.Value = "Process cancelled.";
            system(['C:\Users\user\Desktop\UCT 2023\EEE3097S DESIGN\3097-Group-8-2023\Pi Connection\kill_recording.bat']);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.STARTButton = uibutton(app.LeftPanel, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.DISPLAYButton = uibutton(app.LeftPanel, 'push');
            app.DISPLAYButton.ButtonPushedFcn = createCallbackFcn(app, @DISPLAYButtonPushed, true);
            app.DISPLAYButton.Text = 'DISPLAY';
            app.TextAreaLabel = uilabel(app.LeftPanel);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.LeftPanel);
            app.STOPButton = uibutton(app.LeftPanel, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end